classdef FRETExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CardUIFigure                matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        BulkModeButton              matlab.ui.control.Button
        EditFieldFilePath           matlab.ui.control.EditField
        InfoTextArea                matlab.ui.control.TextArea
        SwitchRollingMean           matlab.ui.control.Switch
        RollingmeanEditField        matlab.ui.control.NumericEditField
        RollingmeanEditFieldLabel   matlab.ui.control.Label
        SwitchDownsampling          matlab.ui.control.Switch
        DownsamplingEditField       matlab.ui.control.NumericEditField
        DownsamplingEditFieldLabel  matlab.ui.control.Label
        SaveAsxlsxButton            matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        GridLayout3                 matlab.ui.container.GridLayout
        UIAxes4                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes1                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Information_Area
        measurementOrigData
        measurementPlotData
        settings
        processedData
    end

    methods (Access = private)

        function plotData(app)
            if(isempty(app.measurementPlotData) == 0)
                if app.SwitchRollingMean.Value == false && app.SwitchDownsampling.Value == false
                    plot(app.UIAxes1,app.measurementPlotData(1).time, app.measurementPlotData(1).r_cor);
                    plot(app.UIAxes2,app.measurementPlotData(2).time, app.measurementPlotData(2).r_cor);
                    plot(app.UIAxes3,app.measurementPlotData(3).time, app.measurementPlotData(3).r_cor);
                    plot(app.UIAxes4,app.measurementPlotData(4).time, app.measurementPlotData(4).r_cor);
                else
                    plot(app.UIAxes1,app.processedData(1).time, app.processedData(1).r_cor);
                    plot(app.UIAxes2,app.processedData(2).time, app.processedData(2).r_cor);
                    plot(app.UIAxes3,app.processedData(3).time, app.processedData(3).r_cor);
                    plot(app.UIAxes4,app.processedData(4).time, app.processedData(4).r_cor);
                end
            end
        end

        function processData(app)
            if(isempty(app.measurementPlotData) == 0)
                app.processedData = app.measurementPlotData;
                if app.SwitchRollingMean.Value == true
                    app.processedData(1).r_cor = movmean(app.measurementPlotData(1).r_cor, app.RollingmeanEditField.Value);
                    app.processedData(2).r_cor = movmean(app.measurementPlotData(2).r_cor, app.RollingmeanEditField.Value);
                    app.processedData(3).r_cor = movmean(app.measurementPlotData(3).r_cor, app.RollingmeanEditField.Value);
                    app.processedData(4).r_cor = movmean(app.measurementPlotData(4).r_cor, app.RollingmeanEditField.Value);
                end
                if app.SwitchDownsampling.Value == true
                    app.processedData(1).r_cor = app.processedData(1).r_cor(1:app.DownsamplingEditField.Value:numel(app.processedData(1).r_cor));
                    app.processedData(2).r_cor = app.processedData(2).r_cor(1:app.DownsamplingEditField.Value:numel(app.processedData(2).r_cor));
                    app.processedData(3).r_cor = app.processedData(3).r_cor(1:app.DownsamplingEditField.Value:numel(app.processedData(3).r_cor));
                    app.processedData(4).r_cor = app.processedData(4).r_cor(1:app.DownsamplingEditField.Value:numel(app.processedData(4).r_cor));
                    app.processedData(1).time = app.processedData(1).time(1:app.DownsamplingEditField.Value:numel(app.processedData(1).time));
                    app.processedData(2).time = app.processedData(2).time(1:app.DownsamplingEditField.Value:numel(app.processedData(2).time));
                    app.processedData(3).time = app.processedData(3).time(1:app.DownsamplingEditField.Value:numel(app.processedData(3).time));
                    app.processedData(4).time = app.processedData(4).time(1:app.DownsamplingEditField.Value:numel(app.processedData(4).time));
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
            if file ~= 0
                d = uiprogressdlg(app.CardUIFigure,'Title','Loading file',...
                    'Indeterminate','on');
                loadData =  load(fullfile(path, file), '-mat');
                close(d);

                app.settings = loadData.Settings;
                app.measurementOrigData = loadData.measurementOrigData;
                app.measurementPlotData = loadData.measurementPlotData;
                app.Information_Area = loadData.Information_Area;
                app.InfoTextArea.Value = app.Information_Area;
                app.EditFieldFilePath.Value = file;
                processData(app);
                plotData(app);
            end
        end

        % Value changed function: DownsamplingEditField, 
        % ...and 3 other components
        function SwitchRollingMeanValueChanged(app, event)
            processData(app);
            plotData(app);
        end

        % Button pushed function: SaveAsxlsxButton
        function SaveAsxlsxButtonPushed(app, event)
            [file, path] = uiputfile(append(app.EditFieldFilePath.Value, '.xlsx'), 'Save as Excel file');
            if file ~= 0

                arrayData = cell(4,1) ;
                rows = zeros(4,1);
                for j = 1:4
                    dataCell = struct2cell(struct(app.processedData(j)));
                    dataMat = cell2mat(dataCell)';
                    arrayData{j} = array2table(dataMat, 'VariableNames',{append('time_',num2str(j)), append('value_',num2str(j))});
                    rows(j) = size(arrayData{j} ,1);
                end
                minRows = min(rows);
                arrayData{1} = arrayData{1}(1:minRows,:);
                arrayData{2} = arrayData{2}(1:minRows,:);
                arrayData{3} = arrayData{3}(1:minRows,:);
                arrayData{4} = arrayData{4}(1:minRows,:);
                tableData = [arrayData{1} arrayData{2} arrayData{3} arrayData{4}];
                writetable(tableData, fullfile(path, file), 'Sheet', 'Data', 'WriteVariableNames', true);
            end

        end

        % Button pushed function: BulkModeButton
        function BulkModeButtonPushed(app, event)
    inputDirectory = uigetdir('', 'Select directory containing MAT files');
    if inputDirectory ~= 0
        outputDirectory = uigetdir('', 'Select directory to save Excel files');
        if outputDirectory ~= 0
            matFiles = dir(fullfile(inputDirectory, '*.mat'));
            numFiles = length(matFiles);
            for i = 1:numFiles
                            d = uiprogressdlg(app.CardUIFigure,'Title',sprintf('Loading file %d of %d', i, numFiles),...
                'Indeterminate','on');
            loadData = load(fullfile(inputDirectory, matFiles(i).name), '-mat');
            close(d);

            app.settings = loadData.Settings;
            app.measurementOrigData = loadData.measurementOrigData;
            app.measurementPlotData = loadData.measurementPlotData;
            app.Information_Area = loadData.Information_Area;
            app.InfoTextArea.Value = app.Information_Area;
            app.EditFieldFilePath.Value = matFiles(i).name;
            processData(app);
            plotData(app);

                d = uiprogressdlg(app.CardUIFigure,'Title',sprintf('Saving file %d of %d', i, numFiles),...
                    'Indeterminate','on');
                outputFileName = [matFiles(i).name(1:end-4) '.xlsx'];
                outputFilePath = fullfile(outputDirectory, outputFileName);

                arrayData = cell(4,1);
                rows = zeros(4,1);
                for j = 1:4
                    dataCell = struct2cell(struct(app.processedData(j)));
                    dataMat = cell2mat(dataCell)';
                    arrayData{j} = array2table(dataMat, 'VariableNames',{append('time_',num2str(j)), append('value_',num2str(j))});
                    rows(j) = size(arrayData{j} ,1);
                end
                minRows = min(rows);
                arrayData{1} = arrayData{1}(1:minRows,:);
                arrayData{2} = arrayData{2}(1:minRows,:);
                arrayData{3} = arrayData{3}(1:minRows,:);
                arrayData{4} = arrayData{4}(1:minRows,:);
                tableData = [arrayData{1} arrayData{2} arrayData{3} arrayData{4}];
                writetable(tableData, outputFilePath, 'Sheet', 'Data', 'WriteVariableNames', true);
                close(d);
            end
        end
    end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CardUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {712, 712};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {361, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CardUIFigure = uifigure('Visible', 'off');
            app.CardUIFigure.Name = 'Card';
            app.CardUIFigure.Icon = fullfile(pathToMLAPP, 'Icon.jpg');
            app.CardUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CardUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadButton = uibutton(app.GridLayout2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Tooltip = {'Select a .mat file to load.'};
            app.LoadButton.Text = 'Load';
            app.SaveAsxlsxButton = uibutton(app.GridLayout2, 'push');
            app.SaveAsxlsxButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsxlsxButtonPushed, true);
            app.SaveAsxlsxButton.Tooltip = {'Save file as .xlsx table.'};
            app.SaveAsxlsxButton.Text = 'Save xlsx';
            app.DownsamplingEditFieldLabel = uilabel(app.GridLayout2);
            app.DownsamplingEditFieldLabel.Tooltip = {'Downsampling is the process of reducing the sampling rate of a signal by selecting and keeping only a subset of its original samples.'};
            app.DownsamplingEditFieldLabel.Text = 'Downsampling';
            app.DownsamplingEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DownsamplingEditField.Limits = [1 Inf];
            app.DownsamplingEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchRollingMeanValueChanged, true);
            app.DownsamplingEditField.Tooltip = {'Take every nth data point.'};
            app.DownsamplingEditField.Value = 1;
            app.SwitchDownsampling = uiswitch(app.GridLayout2, 'slider');
            app.SwitchDownsampling.ItemsData = [0 1];
            app.SwitchDownsampling.ValueChangedFcn = createCallbackFcn(app, @SwitchRollingMeanValueChanged, true);
            app.SwitchDownsampling.Tooltip = {'Use downsampling.'};
            app.SwitchDownsampling.Value = 0;
            app.RollingmeanEditFieldLabel = uilabel(app.GridLayout2);
            app.RollingmeanEditFieldLabel.Tooltip = {'Rolling mean averaging is a method of smoothing a time series data by calculating the average of a sliding window of consecutive data points.'};
            app.RollingmeanEditFieldLabel.Text = 'Rolling mean';
            app.RollingmeanEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RollingmeanEditField.Limits = [1 Inf];
            app.RollingmeanEditField.ValueChangedFcn = createCallbackFcn(app, @SwitchRollingMeanValueChanged, true);
            app.RollingmeanEditField.Tooltip = {'Rolling mean average factor.'};
            app.RollingmeanEditField.Value = 1;
            app.SwitchRollingMean = uiswitch(app.GridLayout2, 'slider');
            app.SwitchRollingMean.ItemsData = [0 1];
            app.SwitchRollingMean.ValueChangedFcn = createCallbackFcn(app, @SwitchRollingMeanValueChanged, true);
            app.SwitchRollingMean.Tooltip = {'Use rolling mean averaging.'};
            app.SwitchRollingMean.Value = 0;
            app.InfoTextArea = uitextarea(app.GridLayout2);
            app.InfoTextArea.Editable = 'off';
            app.InfoTextArea.Tooltip = {'Infotext from the file.'};
            app.EditFieldFilePath = uieditfield(app.GridLayout2, 'text');
            app.EditFieldFilePath.Editable = 'off';
            app.EditFieldFilePath.Tooltip = {'Filepath of the loaded file.'};
            app.BulkModeButton = uibutton(app.GridLayout2, 'push');
            app.BulkModeButton.ButtonPushedFcn = createCallbackFcn(app, @BulkModeButtonPushed, true);
            app.BulkModeButton.Tooltip = {'Select directory containing MAT files. Then select a directory where the .xlsx tables will be saved. All files in the directory will be converted and averaged/downsampled if selected. '};
            app.BulkModeButton.Text = 'Bulk Mode';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes1 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.CardUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FRETExport

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CardUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CardUIFigure)
        end
    end
end